classdef learningquiz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Slider                     matlab.ui.control.Slider
        SliderLabel                matlab.ui.control.Label
        Image4                     matlab.ui.control.Image
        Image3                     matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        PlayAudioButton            matlab.ui.control.Button
        SubmitRatingButton         matlab.ui.control.Button
        ResultsPanel               matlab.ui.container.Panel
        LearningStyleResultsLabel  matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        UIAxes                     matlab.ui.control.UIAxes
        SkipButton                 matlab.ui.control.Button
        OptionButton3              matlab.ui.control.Button
        OptionButton2              matlab.ui.control.Button
        QuestionLabel              matlab.ui.control.Label
        OptionButton1              matlab.ui.control.Button
    end

    
    properties (Access = private)
        CurrentQuestionIndex = 1
        Scores = struct('Visual', 0, 'Auditory', 0, 'Kinesthetic', 0)
        QuizData
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Define quiz questions and options
           app.QuizData(1).question = "How do you best remember information?";
           app.QuizData(1).options = {"By seeing images", "By hearing explanations", "By doing activities"};
           app.QuizData(1).type = {"Visual", "Auditory", "Kinesthetic"};
           app.QuizData(1).questionType = "multiple choice";
      
           app.QuizData(2).question = "What helps you concentrate?";
           app.QuizData(2).options = {"Reading notes", "Listening to music", "Taking walks"};
           app.QuizData(2).type = {"Visual", "Auditory", "Kinesthetic"};
           app.QuizData(2).questionType = "multiple choice";

           app.QuizData(3).question = "What's your ideal study tool?";
           app.QuizData(3).options = {"Flashcards", "Audio recordings", "Hands-on tools"};
           app.QuizData(3).type = {"Visual", "Auditory", "Kinesthetic"};
           app.QuizData(3).questionType = "basic multiple choice";
           
          app.QuizData(4).question = "I find diagrams/images helpful when learning (1 = low, 5 = high)";
          app.QuizData(4).type = "Visual";
          app.QuizData(4).questionType = "slider";

          app.QuizData(5).question = "Rewriting notes is the best way to memorize content (1 = low, 5 = high)";
          app.QuizData(5).type = "Kinesthetic";
          app.QuizData(5).questionType = "slider";

          app.QuizData(6).question = "I replay lectures to help me understand new concepts (1 = low, 5 = high)";
          app.QuizData(6).type = "Auditory";
          app.QuizData(6).questionType = "slider";

          app.QuizData(7).question = "Follow the instructions in the audio.";
          app.QuizData(7).options = {"1", "3", "7"};
          app.QuizData(7).type = {"Visual", "Auditory", "Kinesthetic"};
          app.QuizData(7).questionType = "audio";

          app.QuizData(8).question = "Which environment, as pictured, would you most prefer to learn in?";
          app.QuizData(8).options = {"Coffee Shop", "Outdoors", "Colorful Lounge"};
          app.QuizData(8).type = {"Auditory", "Kinesthetic", "Visual"};
          app.QuizData(8).questionType = "image-based multiple choice";


           % Initialize score tracking
           app.Scores.Visual = 0;
           app.Scores.Auditory = 0;
           app.Scores.Kinesthetic = 0;
      
           app.CurrentQuestionIndex = 1;
      
           % Display first question
           displayQuestion(app);
        end
       
        function updateScores(app, selectedIndex)
            type = app.QuizData(app.CurrentQuestionIndex).type{selectedIndex};
            app.Scores.(type) = app.Scores.(type) + 1;
            
            app.CurrentQuestionIndex = app.CurrentQuestionIndex + 1;
            
            if app.CurrentQuestionIndex > length(app.QuizData)
                showResults(app);
            else
                displayQuestion(app);
            end
        end
       
        function updateSliderScore(app)
            q = app.QuizData(app.CurrentQuestionIndex);
            sliderValue = round(app.Slider.Value);
            app.Scores.(q.type) = app.Scores.(q.type) + sliderValue;
            
            app.CurrentQuestionIndex = app.CurrentQuestionIndex + 1;
            
            if app.CurrentQuestionIndex > length(app.QuizData)
                showResults(app);
            else
              displayQuestion(app);
            end

        end

        % Button pushed function: OptionButton1
        function OptionButton1Pushed(app, event)
             updateScores(app, 1);

        end

        % Button pushed function: OptionButton2
        function OptionButton2Pushed(app, event)
            updateScores(app, 2);

        end

        % Button pushed function: OptionButton3
        function OptionButton3Pushed(app, event)
            updateScores(app, 3);
        end
       
        function displayQuestion(app)
            q = app.QuizData(app.CurrentQuestionIndex);

            if strcmp(q.questionType, "slider")
                app.OptionButton1.Visible = 'off';
                app.OptionButton2.Visible = 'off';
                app.OptionButton3.Visible = 'off';
                app.SkipButton.Visible = 'on';
                app.Slider.Visible = 'on';
                app.SliderLabel.Visible = 'on';
                app.SubmitRatingButton.Visible = 'on';
                app.PlayAudioButton.Visible = "off";

                app.QuestionLabel.Text = q.question;

                app.Slider.Value = 3; % Default value

            elseif strcmp(q.questionType, "audio")
               app.OptionButton1.Visible = 'on';
                app.OptionButton2.Visible = 'on';
                app.OptionButton3.Visible = 'on';
                app.SkipButton.Visible = 'on';
                app.Slider.Visible = 'off';
                app.SliderLabel.Visible = 'off';
                app.SubmitRatingButton.Visible = 'off';
                app.PlayAudioButton.Visible = "on";
                app.Image2.Visible = 'off';
                app.Image3.Visible = 'off';
                app.Image4.Visible = 'off';

               app.QuestionLabel.Text = q.question;
               app.OptionButton1.Text = q.options{1};
               app.OptionButton2.Text = q.options{2};
               app.OptionButton3.Text = q.options{3};


            elseif strcmp (q.questionType, "multiple choice")
                % Multiple-choice question
                app.OptionButton1.Visible = 'on';
                app.OptionButton2.Visible = 'on';
                app.OptionButton3.Visible = 'on';
                app.SkipButton.Visible = 'on';
                app.Slider.Visible = 'off';
                app.SliderLabel.Visible = 'off';
                app.SubmitRatingButton.Visible = 'off';
                app.PlayAudioButton.Visible = "off";
                app.Image2.Visible = 'off';
                app.Image3.Visible = 'off';
                app.Image4.Visible = 'off';

                app.QuestionLabel.Text = q.question;
                app.OptionButton1.Text = q.options{1};
                app.OptionButton2.Text = q.options{2};
                app.OptionButton3.Text = q.options{3};


            elseif strcmp (q.questionType, "image-based multiple choice")
                % Multiple-choice question with images
                app.OptionButton1.Visible = 'on';
                app.OptionButton2.Visible = 'on';
                app.OptionButton3.Visible = 'on';
                app.SkipButton.Visible = 'on';
                app.Slider.Visible = 'off';
                app.SliderLabel.Visible = 'off';
                app.SubmitRatingButton.Visible = 'off';
                app.PlayAudioButton.Visible = 'off';
                app.Image2.Visible = 'on';
                app.Image3.Visible = 'on';
                app.Image4.Visible = 'on';

                app.QuestionLabel.Text = q.question;
                app.OptionButton1.Text = q.options{1};
                app.OptionButton2.Text = q.options{2};
                app.OptionButton3.Text = q.options{3};


            end
       

        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
           
           % skip to next question
           app.CurrentQuestionIndex = app.CurrentQuestionIndex + 1;
           if app.CurrentQuestionIndex > length(app.QuizData)
               showResults(app);
           else
               displayQuestion(app);
               app.ButtonGroup.SelectedObject = []; % clear selection
           end
        end

        function showResults(app)
            app.QuestionLabel.Visible = 'off';
            app.OptionButton1.Visible = 'off';
            app.OptionButton2.Visible = 'off';
            app.OptionButton3.Visible = 'off';
            app.SkipButton.Visible = 'off';
            app.Slider.Visible = 'off';         
            app.SliderLabel.Visible = 'off';     
            app.SubmitRatingButton.Visible = 'off';
            app.PlayAudioButton.Visible = "off";
            app.ResultsPanel.Visible = 'on';
            app.Image2.Visible = 'off';
            app.Image3.Visible = 'off';
            app.Image4.Visible = 'off';
            
            types = fieldnames(app.Scores);
            values = structfun(@(x) x, app.Scores);
            [~, idx] = max(values);
            maxType = types{idx};

            app.LearningStyleResultsLabel.Text = ['Congratulations! You are a ' maxType ' learner!'];
            
        end

        % Button pushed function: SubmitRatingButton
        function SubmitRatingButtonPushed(app, event)
            updateSliderScore(app);


        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            q = app.QuizData(app.CurrentQuestionIndex);
            [y, Fs] = audioread("sample audio for matlab.wav");
               sound(y, Fs)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptionButton1 = uibutton(app.UIFigure, 'push');
            app.OptionButton1.ButtonPushedFcn = createCallbackFcn(app, @OptionButton1Pushed, true);
            app.OptionButton1.Text = 'Option Button 1';
            app.QuestionLabel = uilabel(app.UIFigure);
            app.QuestionLabel.Text = 'Question Label';
            app.OptionButton2 = uibutton(app.UIFigure, 'push');
            app.OptionButton2.ButtonPushedFcn = createCallbackFcn(app, @OptionButton2Pushed, true);
            app.OptionButton2.Text = 'Option Button 2';
            app.OptionButton3 = uibutton(app.UIFigure, 'push');
            app.OptionButton3.ButtonPushedFcn = createCallbackFcn(app, @OptionButton3Pushed, true);
            app.OptionButton3.Text = 'Option Button 3';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.ResultsPanel.Visible = 'off';
            app.UIAxes = uiaxes(app.ResultsPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.ResultsPanel);
            app.LearningStyleResultsLabel = uilabel(app.ResultsPanel);
            app.LearningStyleResultsLabel.Text = 'Learning Style Results';
            app.SubmitRatingButton = uibutton(app.UIFigure, 'push');
            app.SubmitRatingButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitRatingButtonPushed, true);
            app.SubmitRatingButton.Text = 'Submit Rating';
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'coffee shop.jpg');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'outdoor.jpg');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'colorful.jpg');
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 5];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = learningquiz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end